classdef visual_calibration_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        lambdafield                 matlab.ui.control.EditField
        LengthscaleEditFieldLabel   matlab.ui.control.Label
        gammafield                  matlab.ui.control.EditField
        PriorvariancedecayEditFieldLabel_2  matlab.ui.control.Label
        GeneratefiguretosaveButton  matlab.ui.control.Button
        lap_axes                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % This app allows for "visual calibration" of the Laplace
    % diagnostic with some kind of T density as the test function.
    % The test function can either be a d-dimensional T density with
    % v degrees of freedom (if ind = false), or the d-dimensional
    % product of univariate T densities with v degrees of freedom
    % (if ind = true). The interrogation grid is "cross-shaped":
    % preliminary points are placed at the origin and along each axis
    % at distances +/-{delt, 2*delt, ..., r}. The user can specify
    % values for the diagnostic hyperparameters lambda and gamma
    % (with mathematical expressions being allowed), and the app will
    % generate two plots: one showing the difference between the
    % un-weighted GP posterior mean and the true function; and one
    % showing the posterior distribution for the integral.
    % For the latter, the precision hyperparameter alpha is
    % automatically calculated to ensure the LA is on the border of
    % the rejection region. Finally, the user may push a button
    % to generate a saveable figure like Figures 1-3 in the
    % manuscript.
    
    properties (Access = private)
        d; % Dimensionality
        s_star; % Preliminary interrogation grid
        v; % Degrees of freedom for test function (f)
        logf_at_mode; % Value f at the mode (origin) on the log scale
        f_interr % Values of f at interrogation points
        T_elem; % Value of the diagonal elements in matrix T
        s; % Actual interrogation grid (T*s_star)
        lap_approx; % Laplace approximation to \int f
        g; % Gaussian integration measure
        alpha; % Prior precision hyperparameter
        xvals; % Points on x_1 axis for plotting
        yvals; % Points on x_2 axis for plotting
        grid_size1; grid_size2; % Size of grid used for plotting
        true_vals; % Values of function at [xvals yvals]
        prior_mean_vals; % Values of Gaussian approximation to f at 
        % [xvals yvals]
        diff_surf; % Surface plot of mx_1*g - f (the difference
        % between the "un-normalized" posterior GP mean and f)
        point_scat; % Scatterplot of interrogation points  
        mah_dist_s; % Mahalanobis distances between vectors in s
        mah_dist_x_s; % Mahalanobis distance between points in
        % [xgrid ygrid] and s
    end
    
    methods (Access = private)
        function c_t = Cx_0(app, r, lambda)
            % Used to calculate weights for GP and integral
            c_t = exp(-r.^2/(2*lambda^2));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ind, d, v, r, delt)
            % d = Dimensionality of domain
            % r = Number of "standard deviations" for which the grid
            % should extend along each axis (see Sec. 4.1 of
            % manuscript)
            % delt = Increments (in standard deviations) at which to
            % put preliminary points. e.g. r = 3, delt = 1 puts points
            % in s_star at +/-0,1,2,3 along each axis
            % v = Degrees of freedom for f
            % ind: logical; controls whether the f is a product of
            % (independent) 1-D T-distributions (true) or a
            % multivariate (dependent, but uncorrelated)
            % T-distribution (false)
            
            app.v = v;
            app.d = d;

            % Test function
            if ind % Independent T variables
                f = @(x) prod(tpdf(x, app.v), 2);
                hess_at_mode = diag(repelem(-(app.v+1)/app.v, app.d));
                app.logf_at_mode = app.d*(gammaln((app.v+1)/2) -...
                    gammaln(app.v/2) - log(app.v*pi)/2);
                app.T_elem = sqrt(app.v/(app.v+1));
            else % Multivariate T: uncorrelated, but dependent
                f = @(x) mvtpdf(x, eye(app.d), app.v);
                hess_at_mode = diag(repelem(-(app.v+app.d)/app.v,...
                    app.d));
                app.logf_at_mode = gammaln((app.v+app.d)/2) -...
                    gammaln(app.v/2) - app.d*log(app.v*pi)/2;
                app.T_elem = sqrt(app.v/(app.v+app.d));
            end
            
            % Interrogation grid
            s_template = -r:delt:r;
            l = length(s_template);
            app.s_star = zeros([d*(l-1)+1 d]);
            app.s_star(1:l, 1) = s_template;
            for i = 2:d
                app.s_star((i-2)*(l-1)+l+(1:(l-1)), i) =...
                    s_template([1:((l-1)/2) ((l+3)/2):end]);
            end
            app.s = app.T_elem*app.s_star;
            app.mah_dist_s = pdist2(app.s_star, app.s_star);
            
            % Gaussian approximation to f
            gauss_approx = @(x) exp(app.logf_at_mode +...
                sum((x*hess_at_mode).*x, 2)/2);
            
            % Density of integration measure
            app.g = @(x, gamma) mvnpdf(x, zeros([1 d]),...
                -gamma^2*inv(hess_at_mode));
            
            % Laplace approximation
            app.lap_approx = exp(app.logf_at_mode +...
                app.d*(log(app.T_elem) + log(2*pi)/2));
            
            % Interrogation values
            app.f_interr = log(f(app.s));
            
            % Initial plotting stuff
            set(app.UIAxes, 'FontSize', 16);
            set(app.lap_axes, 'FontSize', 16);
            set(app.UIAxes,'TickLabelInterpreter','latex'); 
            set(app.lap_axes,'TickLabelInterpreter','latex');
            
            if app.d == 1 % Univariate case
                title(app.UIAxes, ['$$m^x_1 \cdot g - \tau_{'...
                    num2str(app.v) ', ' num2str(d) '}$$'],...
                    'Interpreter',"latex", 'FontSize', 20);
                xlabel(app.UIAxes, '$x$', 'Interpreter', 'latex',...
                    'FontSize', 18);
                
                % Mesh of points for plotting
                grid_bounds = [min(app.s)-2*app.T_elem...
                    max(app.s)+2*app.T_elem];
                app.xvals = linspace(grid_bounds(1),...
                    grid_bounds(2), 81);
                app.grid_size1 = length(app.xvals);
                
                app.prior_mean_vals = gauss_approx(app.xvals');
                app.true_vals = f(app.xvals');
                app.mah_dist_x_s = pdist2(app.xvals'/app.T_elem,...
                    app.s_star);
                
                app.point_scat = scatter(app.UIAxes, app.s,...
                    repelem(0, length(app.f_interr)), 64, 'filled',...
                    'MarkerFaceColor', 'k');            
                hold(app.UIAxes, 'on')
                app.diff_surf = gobjects(1);
                
            elseif app.d == 2 % Bivariate case
                title(app.UIAxes, ['$$m^x_1 \cdot g - \tau_{' ...
                    num2str(app.v) ', ' num2str(app.d) '}$$'],...
                    'Interpreter',"latex", 'FontSize', 20);
                xlabel(app.UIAxes, '$x_1$', 'Interpreter', 'latex',...
                    'FontSize', 18);
                ylabel(app.UIAxes, '$x_2$', 'Interpreter', 'latex',...
                    'FontSize', 18);
                
                grid_bounds = [min(app.s(:,1))-2*app.T_elem...
                    max(app.s(:,1))+2*app.T_elem...
                    min(app.s(:,2))-2*app.T_elem...
                    max(app.s(:,2))+2*app.T_elem];
                grid_ratio = (grid_bounds(4) - grid_bounds(3))/...
                    (grid_bounds(2) - grid_bounds(1));
                [xgrid, ygrid] = meshgrid(linspace(grid_bounds(1),...
                    grid_bounds(2), 81),...
                    linspace(grid_bounds(3), grid_bounds(4), 81));
                [app.grid_size1, app.grid_size2] = size(xgrid);
                app.xvals = xgrid(:);
                app.yvals = ygrid(:);
                
                app.true_vals = reshape(f([app.xvals app.yvals]),...
                    app.grid_size1, app.grid_size2);
                app.prior_mean_vals =...
                    reshape(gauss_approx([app.xvals app.yvals]),...
                    app.grid_size1, app.grid_size2);
                app.mah_dist_x_s = pdist2([app.xvals app.yvals]/...
                    app.T_elem, app.s_star);
                
                app.point_scat = scatter3(app.UIAxes, app.s(:,1),...
                    app.s(:,2), repelem(0, length(app.f_interr)),...
                    64, 'filled', 'MarkerFaceColor', 'k');
                hold(app.UIAxes, 'on')
                pbaspect(app.UIAxes,...
                    [1 grid_ratio geomean([grid_ratio 1/grid_ratio])])
                app.diff_surf = gobjects(1);
                
            else % Higher-dimensional case
                % Here we plot a 2-D "slice" with all but the first 2
                % coordinates fixed at the origin
                title(app.UIAxes, ['$$m^x_1 \cdot g - \tau_{' ...
                    num2str(app.v) ', ' num2str(app.d) '}$$'],...
                    'Interpreter',"latex", 'FontSize', 20);
                subtitle(app.UIAxes,...
                    '$\left(x_i = 0\ \mathrm{for}\  i > 2\right)$',...
                    'Interpreter',"latex", 'FontSize', 16);
                xlabel(app.UIAxes, '$x_1$', 'Interpreter', 'latex',...
                    'FontSize', 18);
                ylabel(app.UIAxes, '$x_2$', 'Interpreter', 'latex',...
                    'FontSize', 18);
                
                grid_bounds = [min(app.s(:,1))-2*app.T_elem...
                    max(app.s(:,1))+2*app.T_elem...
                    min(app.s(:,2))-2*app.T_elem...
                    max(app.s(:,2))+2*app.T_elem];
                grid_ratio = (grid_bounds(4) - grid_bounds(3))/...
                    (grid_bounds(2) - grid_bounds(1));
                [xgrid, ygrid] = meshgrid(linspace(grid_bounds(1),...
                    grid_bounds(2), 81),...
                    linspace(grid_bounds(3), grid_bounds(4), 81));
                [app.grid_size1, app.grid_size2] = size(xgrid);
                app.xvals = xgrid(:);
                app.yvals = ygrid(:);

                app.true_vals = reshape(f([app.xvals app.yvals...
                    repelem(0, length(app.xvals), app.d-2)]),...
                    app.grid_size1, app.grid_size2);
                app.prior_mean_vals =...
                    reshape(gauss_approx([app.xvals app.yvals...
                    repelem(0,length(app.xvals), app.d-2)]),...
                    app.grid_size1, app.grid_size2);
                 app.mah_dist_x_s = pdist2([app.xvals app.yvals...
                    zeros(length(app.xvals), app.d-2)]/app.T_elem,...
                    app.s_star);
                 
                app.point_scat = scatter3(app.UIAxes,...
                    app.s(1:(2*l-1),1), app.s(1:(2*l-1),2),...
                    repelem(0, length(app.f_interr(1:(2*l-1)))), ...
                    64, 'filled', 'MarkerFaceColor', 'k');
                hold(app.UIAxes, 'on')
                pbaspect(app.UIAxes,...
                    [1 grid_ratio geomean([grid_ratio 1/grid_ratio])])
                app.diff_surf = gobjects(1);
            end
            
            title(app.lap_axes, ...
                'Posterior of $F$, $\mathcal{N}\left(m_1, C_1\right)$', ...
                'Interpreter', 'latex', 'FontSize', 20);
        end

        % Value changed function: gammafield, lambdafield
        function HyperparamValuesChanged(app, event)
            % Get values of lambda and gamma from fields
            lambda = eval(app.lambdafield.Value);
            gamma = eval(app.gammafield.Value);
          
            % Initialize
            delete(app.diff_surf);
            hold(app.lap_axes,'off')
            
            % Get interrogation values
            if app.d == 1
            % Slightly different because mvnpdf doesn't work right
                interrs = exp(app.d*(log(gamma*app.T_elem)) + ...
                    app.f_interr - log(normpdf(app.s_star/gamma)))- ...
                    exp(app.d*log(2*pi*gamma*app.T_elem)+ ...
                    app.logf_at_mode + ...
                    log(normpdf(sqrt(gamma^2-1)*app.s_star/gamma)));
            else
                interrs = exp(app.d*(log(gamma*app.T_elem)) + ...
                    app.f_interr - log(mvnpdf(app.s_star/gamma)))- ...
                    exp(app.d*log(2*pi*gamma*app.T_elem)+ ...
                    app.logf_at_mode + ...
                    log(mvnpdf(sqrt(gamma^2-1)*app.s_star/gamma)));
            end
            
            % Gram matrix
            gram_mat = Cx_0(app, app.mah_dist_s, lambda);
            pointz = gram_mat\interrs;
            
            % Weights for GP posterior mean
            GP_weightz = Cx_0(app, app.mah_dist_x_s, lambda);

            % Plot difference between un-weighted GP posterior mean
            % and true function
            if app.d == 1
                delete(app.diff_surf);
                warning('off');
                app.diff_surf = plot(app.UIAxes, app.xvals,...
                    app.prior_mean_vals +...
                    app.g(app.xvals', gamma).*GP_weightz*pointz -...
                    app.true_vals, 'LineWidth', 1.25, 'Color', 'k');  
            else
                delete(app.diff_surf);
                warning('off');
                app.diff_surf = surf(app.UIAxes,...
                    unique(app.xvals(:)), unique(app.yvals(:)),...
                    app.prior_mean_vals + ...
                    reshape(app.g([app.xvals app.yvals ...
                    zeros(length(app.xvals), app.d-2)], gamma).* ...
                    GP_weightz*pointz, app.grid_size1, ...
                    app.grid_size2) - app.true_vals, 'FaceAlpha', ...
                    0.6, 'EdgeColor', [0.5 0.5 0.5]);
            
            end

            legend(app.UIAxes, [app.point_scat], ...
                {'Interrogation points'}, 'Location', ...
                'southoutside', 'interpreter', 'latex', ...
                'FontSize',14);

            % Get posterior mean and variance for integral diagnostic
            int_weightz = (lambda^2/(gamma^2+lambda^2))^(app.d/2)*...
                exp(-sum(app.s_star.^2, 2)/(2*(gamma^2+lambda^2)));
            post_mean = app.lap_approx +int_weightz'*pointz;

            C0 = (lambda^2/(2*gamma^2 + lambda^2))^(app.d/2);
            post_var_unscaled = C0 -...
                (int_weightz'/gram_mat)*int_weightz;
            % Set alpha to ensure LA is on borderline of rejection
            app.alpha = abs(1.96*sqrt(post_var_unscaled)* ...
                exp(app.logf_at_mode+app.d*log(app.T_elem))/ ...
                (post_mean - app.lap_approx))^(2/app.d);
            disp(['alpha: ' num2str(app.alpha)]);
            post_var = post_var_unscaled*exp(app.d*(2* ...
                log(app.T_elem) - log(app.alpha))+2*app.logf_at_mode);
            
            disp(['Posterior integral mean: ' num2str(post_mean)]);
            disp(['Posterior integral variance: ' num2str(post_var)]);
            disp(1.96*sqrt(post_var));
            
            % Plot integral diagnostic
            lower_bound = norminv(0.025, post_mean, sqrt(post_var));
            upper_bound = norminv(0.975, post_mean, sqrt(post_var));
            lap_dist = fplot(app.lap_axes, ...
                @(x) normpdf(x, post_mean, sqrt(post_var)),...
                [lower_bound - 0.35*(upper_bound - lower_bound) ...
                upper_bound + 0.35*(upper_bound - lower_bound)], ...
                '-k','LineWidth', 1.5);
            hold(app.lap_axes, 'on')
            quant_line = xline(app.lap_axes, upper_bound, ':k',...
                'LineWidth', 1.35);
            lap_line = xline(app.lap_axes,app.lap_approx, '--b', ...
                'LineWidth', 1.25);
            true_line = xline(app.lap_axes,1, '--r', ...
                'LineWidth', 1.25);
            quant_line1 = xline(app.lap_axes, lower_bound, ':k',...
                'LineWidth', 1.35);
            legend(app.lap_axes, ...
                [lap_dist, quant_line, lap_line, true_line],...
                {'Integral posterior', '95\% interval',...
                'Laplace approximation', 'True integral value'}, ...
                'Location', 'southoutside', 'interpreter', 'latex',...
                'FontSize', 14);
        end

        % Button pushed function: GeneratefiguretosaveButton
        function GeneratefiguretosaveButtonPushed(app, event)
            % Create figure
            fig = tiledlayout(1,3, 'TileSpacing', 'Compact', ...
                'Padding', 'Compact');
            
            % GP posterior mean plot
            hSub1 = nexttile([1 2]);
            set(gca, 'FontSize', 20);
            copyobj(app.UIAxes.Children, hSub1);
            set(gca,'TickLabelInterpreter','latex'); 
 
            title(['$$m^x_1 \cdot g - \tau_{' num2str(app.v) ', ' ...
                num2str(app.d) '}$$'], 'Interpreter',"latex", ...
                'FontSize', 24);
            if app.d > 2
                subtitle(...
                    '$\left(x_i = 0\ \mathrm{for}\  i > 2\right)$',...
                    'Interpreter',"latex", 'FontSize', 20);
            end
            
            if app.d == 1
                xlabel('$x$', 'Interpreter', 'latex', 'FontSize', 22);
            else
                xlabel('$x_1$', 'Interpreter', 'latex', ...
                    'FontSize', 22);
                ylabel('$x_2$', 'Interpreter', 'latex', ...
                    'FontSize', 22);
            end
            
            legend({'Interrogation points'},...
                'Location', 'southoutside', 'interpreter', 'latex', ...
                'FontSize', 20);
            pbaspect(hSub1, app.UIAxes.PlotBoxAspectRatio)

           % Laplace diagnostic plot
           hSub2 = nexttile;
            set(gca, 'FontSize', 20)
            copyobj(app.lap_axes.Children, hSub2);
            set(gca,'TickLabelInterpreter','latex'); 
            legend({'Integral posterior', '95\% interval',...
                'Laplace approximation', 'True integral value'}, ...
                'Location', 'southoutside', 'interpreter', 'latex', ...
                'FontSize', 20);
            title(gca, ...
                'Posterior of $F$, $\mathcal{N}\left(m_1, C_1\right)$', ...
                'Interpreter', 'latex', 'FontSize', 24);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.lap_axes = uiaxes(app.UIFigure);
            title(app.lap_axes, 'Laplace Approximation Diagnostic')
            app.GeneratefiguretosaveButton = uibutton(app.UIFigure, 'push');
            app.GeneratefiguretosaveButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratefiguretosaveButtonPushed, true);
            app.GeneratefiguretosaveButton.Text = 'Generate figure to save';
            app.PriorvariancedecayEditFieldLabel_2 = uilabel(app.UIFigure);
            app.PriorvariancedecayEditFieldLabel_2.Text = 'Prior variance decay: γ';
            app.gammafield = uieditfield(app.UIFigure, 'text');
            app.gammafield.ValueChangedFcn = createCallbackFcn(app, @HyperparamValuesChanged, true);
            app.gammafield.Value = 'sqrt(1.5*(app.d+app.v)/(app.d+app.v-3))';
            app.LengthscaleEditFieldLabel = uilabel(app.UIFigure);
            app.LengthscaleEditFieldLabel.Text = 'Length-scale: λ';
            app.lambdafield = uieditfield(app.UIFigure, 'text');
            app.lambdafield.ValueChangedFcn = createCallbackFcn(app, @HyperparamValuesChanged, true);
            app.lambdafield.Value = '4.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visual_calibration_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end